classdef PathPlanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes_Part                     matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        PartTab                         matlab.ui.container.Tab
        PartDecimatebyMoveLengthButton  matlab.ui.control.Button
        LengthmmEditFieldLabel          matlab.ui.control.Label
        PartLengthmmEditField           matlab.ui.control.NumericEditField
        PartCombineCollinearMovesButton  matlab.ui.control.Button
        PartStaggerStartbyPointsButton  matlab.ui.control.Button
        PartStaggerPointsEditField      matlab.ui.control.NumericEditField
        StaggerPointsEditFieldLabel     matlab.ui.control.Label
        PartUpdateTorchAnglesButton     matlab.ui.control.Button
        PartAlternateContourDirectionsButton  matlab.ui.control.Button
        PartAlternateContoursEditField  matlab.ui.control.NumericEditField
        EachContoursLabel               matlab.ui.control.Label
        PartRepairEndContinuityButton   matlab.ui.control.Button
        PartTorchAngleDropDown          matlab.ui.control.DropDown
        SegmentTab                      matlab.ui.container.Tab
        ContourTab                      matlab.ui.container.Tab
        MoveTab                         matlab.ui.container.Tab
        WaypointTab                     matlab.ui.container.Tab
        UIAxes_Contour                  matlab.ui.control.UIAxes
        UIAxes_Segment                  matlab.ui.control.UIAxes
        ContourMinSliderLabel           matlab.ui.control.Label
        ContourMinSlider                matlab.ui.control.Slider
        ContourMaxSliderLabel           matlab.ui.control.Label
        ContourMaxSlider                matlab.ui.control.Slider
        ImportPartButton                matlab.ui.control.Button
        ExportPartButton                matlab.ui.control.Button
        ExportFileExtensionDropDownLabel  matlab.ui.control.Label
        LogTextAreaLabel                matlab.ui.control.Label
        LogTextArea                     matlab.ui.control.TextArea
        BuildInfoTextAreaLabel          matlab.ui.control.Label
        BuildInfoTextArea               matlab.ui.control.TextArea
        ExportFileExtensionDropDown     matlab.ui.control.DropDown
        ResetPartViewButton             matlab.ui.control.Button
        ResetContourViewButton          matlab.ui.control.Button
        ResetSegmentViewButton          matlab.ui.control.Button
        UNIVERSITYOFTENNESSEEARCLABORATORYLabel  matlab.ui.control.Label
        ContourMaxUpButton              matlab.ui.control.Button
        ContourMaxDownButton            matlab.ui.control.Button
        ContourMinUpButton              matlab.ui.control.Button
        ContourMinDownButton            matlab.ui.control.Button
        SegmentDropDownLabel            matlab.ui.control.Label
        SegmentDropDown                 matlab.ui.control.DropDown
        ContourDropDownLabel            matlab.ui.control.Label
        ContourDropDown                 matlab.ui.control.DropDown
        MoveDropDownLabel               matlab.ui.control.Label
        MoveDropDown                    matlab.ui.control.DropDown
        WaypointDropDownLabel           matlab.ui.control.Label
        WaypointDropDown                matlab.ui.control.DropDown
        PathManipulationAlgorithmsLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        active_part;
        
        selected_segment = 1;
        selected_contour = 1;
        selected_move = 1;
        selected_waypoint = 1;
        
        n_segments = 1;
        n_contours = 1;
        n_moves = 1;
        n_waypoints = 2; % fixed
    end
    
    methods (Access = private)
        
        % Msgs
        function StartupMsg(app)
            app.LogTextArea.Value = 'Welcome to the UTK ARC Laboratory Path Planner';
            app.LogMsg('Planner version: v0.0.1');
        end%func StartupMsg
    
        function LogMsg(app, msg_string)
            app.LogTextArea.Value = [app.LogTextArea.Value;msg_string];
        end%func ErrorMsg
    
        function ErrorMsg(app,msg_string)
            error_string = strcat('[ERROR]',{' '},msg_string);
            app.LogMsg(error_string);
        end%func ErrorMsg
    
        function ActionMsg(app,msg_string)
            action_string = strcat('[ACTION]',{' '},msg_string);
            app.LogMsg(action_string);
        end%func ActionMsg
        
        function UpdateBuildStats(app)
            part_string = 'Part Selection Contents';
            segment_string = sprintf('Segment: %i/%i',app.selected_segment,app.n_segments);
            contour_string = sprintf('Contour: %i/%i',app.selected_contour,app.n_contours);
            move_string = sprintf('Move: %i/%i',app.selected_move,app.n_moves);
            waypoint_string = sprintf('Waypoint: %i/%i',app.selected_waypoint,app.n_waypoints);
            
            build_stats = app.GetBuildStats;
            part_stats_string = sprintf('Part time: unknown');
            segment_stats_string = sprintf('Segment time: unknown    Segment type: unknown');
            contour_stats_string = sprintf('Contour time: %1.1fsec    Contour length: %1.2fmm',build_stats.contour_time,build_stats.contour_distance);
            move_stats_string = sprintf('Move distance: %1.2fmm    Move speed: %1.2fmm/s',build_stats.move_distance,build_stats.move_speed);
            waypoint_stats_string = sprintf('Waypoint position: unknown    Waypoint torch ZYZ: unknown');
            
            app.BuildInfoTextArea.Value = [part_string newline ...
                segment_string newline ...
                contour_string newline ...
                move_string newline ...
                waypoint_string newline newline ...
                part_stats_string newline ...
                segment_stats_string newline ...
                contour_stats_string newline ...
                move_stats_string newline ...
                waypoint_stats_string];
        end%func UpdateBuildStats
    
        % Plot
        
        function UpdateAllPlots(app)
            app.UpdatePartPlot;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end
        
        function UpdatePartPlot(app)
            PlotTools.PlotPartSimple(app.active_part,app.UIAxes_Part);
            app.ResetPartPlot;
        end%func UpdatePartPlot
    
        function UpdateSegmentPlot(app)
            plot_segment = app.GetSegment(app.selected_segment);
            PlotTools.PlotSegmentSimpleWithSelectedContour(plot_segment,app.selected_contour,app.UIAxes_Segment);
            app.ResetSegmentPlot;
        end%func UpdateSegmentPlot
    
        function UpdateContourPlot(app)
            plot_contour = app.GetContour(app.selected_segment,app.selected_contour);
            PlotTools.PlotContour(plot_contour,app.UIAxes_Contour);
            app.ResetContourPlot;
        end%func UpdateContourPlot
    
        % Plot Formatters
        
        function ResetPartPlot(app)
            view(app.UIAxes_Part,45,45);
        end%func ResetPartPlot
    
        function ResetSegmentPlot(app)
            resetplotview(app.UIAxes_Segment,'InitializeCurrentView');
            xlim(app.UIAxes_Contour,'auto');
            ylim(app.UIAxes_Contour,'auto');
            zlim(app.UIAxes_Contour,'auto');
            view(app.UIAxes_Segment,45,45);
        end%func Reset Segment Plot
    
        function ResetContourPlot(app)
            resetplotview(app.UIAxes_Contour,'InitializeCurrentView');
            xlim(app.UIAxes_Contour,'auto');
            ylim(app.UIAxes_Contour,'auto');
            zlim(app.UIAxes_Contour,'auto');
            view(app.UIAxes_Contour,0,90);
        end%func ResetContourPlot
    
        % Pseudo-getters
        
        function s = GetSegment(app,segment_number)
            s = app.active_part.segments{segment_number};
        end%func GetSegment
    
        function c = GetContour(app,segment_number,contour_number)
            s = app.GetSegment(segment_number);
            c = s.contours{contour_number};
        end%func GetContour
    
        function m = GetMove(app,segment_number,contour_number,move_number)
            c = app.GetContour(segment_number,contour_number);
            m = c.moves{move_number};
        end%func GetMove
    
        function w = GetWaypoint(app,segment_number,contour_number,move_number,waypoint_number)
            m = app.GetMove(segment_number,contour_number,move_number);
            if(waypoint_number == 1)
                w = m.point1;
            elseif(waypoint_number == 2)
                w = m_point2;
            else
                w = [];
            end%if
        end%func GetWaypoint
    
        % UI Jazz
        
        function UpdateElementCounts(app)
            app.n_segments = length(app.active_part.segments);
            
            s = app.active_part.segments{app.selected_segment};
            app.n_contours = length(s.contours);
            
            c = s.contours{app.selected_contour};
            app.n_moves = length(c.moves);
            
            app.UpdateBuildStats;
        end%func UpdateElementCounts
    
        function UpdateDropDowns(app)
            app.UpdateElementCounts;
            
            segment_drop_entries = (1:app.n_segments);
            app.SegmentDropDown.Items = string(segment_drop_entries');
            app.SegmentDropDown.Value = num2str(app.selected_segment);
            
            contour_drop_entries = (1:app.n_contours);
            app.ContourDropDown.Items = string(contour_drop_entries');
            app.ContourDropDown.Value = num2str(app.selected_contour);
            
            move_drop_entries = (1:app.n_moves);
            app.MoveDropDown.Items = string(move_drop_entries');
            app.MoveDropDown.Value = num2str(app.selected_move);
        end
        
        function UpdateAfterImport(app)
            app.UpdateAllPlots;
            app.UpdateDropDowns;
        end
    
        function stats = GetBuildStats(app)
            
            % Calculate Contour Stats
            contour_distance = 0;
            contour_time = 0;
            for i = 1:app.n_moves
                move = app.GetMove(app.selected_segment,app.selected_contour,i);
                p1 = move.point1;
                p2 = move.point2;
                move_speed = abs(p2.speed+p1.speed)/2;
                move_dist = MoveAlgorithms.GetMoveDistance(move);
                move_time = move_dist/move_speed;
                contour_distance = move_dist + contour_distance;
                contour_time = move_time + contour_time;
            end
            
            % Get move stats
            move = app.GetMove(app.selected_segment,app.selected_contour,app.selected_move);
            move_distance = MoveAlgorithms.GetMoveDistance(move);
            p1 = move.point1;
            p2 = move.point2;
            move_speed = abs(p2.speed+p1.speed)/2;
            
            % Output
            stats.contour_distance = contour_distance;
            stats.contour_time = contour_time;
            stats.move_distance = move_distance;
            stats.move_speed = move_speed;
            stats.move_start_pos = [p1.x,p1.y,p1.z];
            stats.move_end_pos = [p2.x,p2.y,p2.z];
            
        end%func GetBuildStats
    end%methods
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StartupMsg;
            
            app.ContourMaxSlider.Limits = [0,10];
            app.ContourMinSlider.Limits = [0,10];
        end

        % Button pushed function: ImportPartButton
        function ImportPartButtonPushed(app, event)
            app.LogMsg('Importing part...');
            [p,import_path] = FileTools.PromptForPartImportFromGOM;
            if(isa(p,'Part'))
                import_string = sprintf('Imported part from %s',import_path);
                app.active_part = p;
            else
                import_string = 'Error: invalid part selection';
                app.active_part = [];
            end%if
            app.LogMsg(import_string);
            app.UpdateAfterImport;
        end

        % Value changed function: SegmentDropDown
        function SegmentDropDownValueChanged(app, event)
            app.selected_segment = str2double(app.SegmentDropDown.Value);
            app.selected_contour = 1;
            app.selected_move = 1;
            app.selected_waypoint = 1;
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Value changed function: ContourDropDown
        function ContourDropDownValueChanged(app, event)
            app.selected_contour = str2double(app.ContourDropDown.Value);
            app.selected_move = 1;
            app.selected_waypoint = 1;
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Value changed function: MoveDropDown
        function MoveDropDownValueChanged(app, event)
            app.selected_move = str2double(app.MoveDropDown.Value);
            app.selected_waypoint = 1;
            app.UpdateDropDowns;
        end

        % Value changed function: WaypointDropDown
        function WaypointDropDownValueChanged(app, event)
            app.selected_waypoint = str2double(app.WaypointDropDown.Value);
            app.UpdateDropDowns;
        end

        % Button pushed function: PartDecimatebyMoveLengthButton
        function PartDecimatebyMoveLengthButtonPushed(app, event)
            move_length = app.PartLengthmmEditField.Value;
            if(move_length < 0.1)
                app.ErrorMsg('Move Length too small!');
            end%if
            
            log_string = sprintf('PART - Decimating Part Contours by Minimum Move Length: %1.3fmm',move_length);
            app.ActionMsg(log_string);
            
            PartAlgorithms.DecimateContoursByMoveLength(app.active_part,move_length);
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Button pushed function: PartCombineCollinearMovesButton
        function PartCombineCollinearMovesButtonPushed(app, event)
            log_string = 'PART - Combining Collinear Moves';
            app.ActionMsg(log_string);
            
            PartAlgorithms.CombineCollinearMoves(app.active_part);
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Button pushed function: PartAlternateContourDirectionsButton
        function PartAlternateContourDirectionsButtonPushed(app, event)
            alternate_contours = app.PartAlternateContoursEditField.Value;
            
            log_string = sprintf('PART - Alternating Part Contours every %i Contours',alternate_contours);
            app.ActionMsg(log_string);
            
            PartAlgorithms.AlternateContourDirections(app.active_part,alternate_contours);
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Button pushed function: PartStaggerStartbyPointsButton
        function PartStaggerStartbyPointsButtonPushed(app, event)
            stagger_points = app.PartStaggerPointsEditField.Value;
            
            log_string = sprintf('PART - Staggering Contour starts every %i points',stagger_points);
            app.ActionMsg(log_string);

            PartAlgorithms.StaggerStartPointsByIndices(app.active_part,stagger_points);
            
            app.UpdateDropDowns;
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end

        % Button pushed function: PartRepairEndContinuityButton
        function PartRepairEndContinuityButtonPushed(app, event)

            log_string = 'PART - Repairing Contour End Continuity';
            app.ActionMsg(log_string);
            
            PartAlgorithms.RepairContourEndContinuity(app.active_part);
            
            app.UpdateDropDowns;
            app.UpdateAllPlots;
        end

        % Button pushed function: PartUpdateTorchAnglesButton
        function PartUpdateTorchAnglesButtonPushed(app, event)
            switch app.PartTorchAngleDropDown.Value
                case 'Inter-Contour Travel X'
                    log_string = 'PART - Updating Torch Angle Using Inter-Contour Vectors with X = Travel Vector';
                    app.ActionMsg(log_string);
            
                    PartAlgorithms.UpdateTorchQuaternionsUsingInterContourVectors(app.active_part);
                case 'Inter-Contour Fixed X'
                    log_string = 'PART - Updating Torch Angle Using Inter-Contour Vectors with X = Fixed';
                    app.ActionMsg(log_string);
            
                    PartAlgorithms.UpdateTorchAnglesUsingInterContourVectorsWithFixedTravelPlane(app.active_part);
                otherwise
                    app.ErrorMsg('PartUpdateTorchAnglesButtonPushed: Unknown case');
            end%switch
            
            app.UpdateSegmentPlot;
            app.UpdateContourPlot;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes_Part = uiaxes(app.UIFigure);
            title(app.UIAxes_Part, 'Part Preview')
            xlabel(app.UIAxes_Part, 'X')
            ylabel(app.UIAxes_Part, 'Y')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PartTab = uitab(app.TabGroup);
            app.PartTab.Title = 'Part';
            app.PartDecimatebyMoveLengthButton = uibutton(app.PartTab, 'push');
            app.PartDecimatebyMoveLengthButton.ButtonPushedFcn = createCallbackFcn(app, @PartDecimatebyMoveLengthButtonPushed, true);
            app.PartDecimatebyMoveLengthButton.Text = 'Decimate by Move Length';
            app.LengthmmEditFieldLabel = uilabel(app.PartTab);
            app.LengthmmEditFieldLabel.Text = 'Length (mm)';
            app.PartLengthmmEditField = uieditfield(app.PartTab, 'numeric');
            app.PartLengthmmEditField.Limits = [0.1 100];
            app.PartLengthmmEditField.Value = 1;
            app.PartCombineCollinearMovesButton = uibutton(app.PartTab, 'push');
            app.PartCombineCollinearMovesButton.ButtonPushedFcn = createCallbackFcn(app, @PartCombineCollinearMovesButtonPushed, true);
            app.PartCombineCollinearMovesButton.Text = 'Combine Collinear Moves';
            app.PartStaggerStartbyPointsButton = uibutton(app.PartTab, 'push');
            app.PartStaggerStartbyPointsButton.ButtonPushedFcn = createCallbackFcn(app, @PartStaggerStartbyPointsButtonPushed, true);
            app.PartStaggerStartbyPointsButton.Text = 'Stagger Start by Points';
            app.PartStaggerPointsEditField = uieditfield(app.PartTab, 'numeric');
            app.PartStaggerPointsEditField.Limits = [1 Inf];
            app.PartStaggerPointsEditField.RoundFractionalValues = 'on';
            app.PartStaggerPointsEditField.ValueDisplayFormat = '%11.g';
            app.PartStaggerPointsEditField.Value = 1;
            app.StaggerPointsEditFieldLabel = uilabel(app.PartTab);
            app.StaggerPointsEditFieldLabel.Text = 'Stagger Points';
            app.PartUpdateTorchAnglesButton = uibutton(app.PartTab, 'push');
            app.PartUpdateTorchAnglesButton.ButtonPushedFcn = createCallbackFcn(app, @PartUpdateTorchAnglesButtonPushed, true);
            app.PartUpdateTorchAnglesButton.Text = 'Update Torch Angles';
            app.PartTorchAngleDropDown = uidropdown(app.PartTab);
            app.PartTorchAngleDropDown.Items = {'Inter-Contour Travel X', 'Inter-Contour Fixed X'};
            app.PartTorchAngleDropDown.Value = 'Inter-Contour Travel X';
            app.PartAlternateContourDirectionsButton = uibutton(app.PartTab, 'push');
            app.PartAlternateContourDirectionsButton.ButtonPushedFcn = createCallbackFcn(app, @PartAlternateContourDirectionsButtonPushed, true);
            app.PartAlternateContourDirectionsButton.Text = 'Alternate Contour Directions';
            app.PartAlternateContoursEditField = uieditfield(app.PartTab, 'numeric');
            app.PartAlternateContoursEditField.Limits = [1 Inf];
            app.PartAlternateContoursEditField.RoundFractionalValues = 'on';
            app.PartAlternateContoursEditField.ValueDisplayFormat = '%11g';
            app.PartAlternateContoursEditField.Value = 1;
            app.EachContoursLabel = uilabel(app.PartTab);
            app.EachContoursLabel.Text = 'Each # Contours';
            app.PartRepairEndContinuityButton = uibutton(app.PartTab, 'push');
            app.PartRepairEndContinuityButton.ButtonPushedFcn = createCallbackFcn(app, @PartRepairEndContinuityButtonPushed, true);
            app.PartRepairEndContinuityButton.Text = 'Repair End Continuity';
            app.SegmentTab = uitab(app.TabGroup);
            app.SegmentTab.Title = 'Segment';
            app.ContourTab = uitab(app.TabGroup);
            app.ContourTab.Title = 'Contour';
            app.MoveTab = uitab(app.TabGroup);
            app.MoveTab.Title = 'Move';
            app.WaypointTab = uitab(app.TabGroup);
            app.WaypointTab.Title = 'Waypoint';
            app.UIAxes_Contour = uiaxes(app.UIFigure);
            title(app.UIAxes_Contour, 'Current Contour')
            xlabel(app.UIAxes_Contour, 'X')
            ylabel(app.UIAxes_Contour, 'Y')
            app.UIAxes_Segment = uiaxes(app.UIFigure);
            title(app.UIAxes_Segment, 'Segment Preview')
            xlabel(app.UIAxes_Segment, 'X')
            ylabel(app.UIAxes_Segment, 'Y')
            app.ContourMinSliderLabel = uilabel(app.UIFigure);
            app.ContourMinSliderLabel.Text = 'Contour Min';
            app.ContourMinSlider = uislider(app.UIFigure);
            app.ContourMaxSliderLabel = uilabel(app.UIFigure);
            app.ContourMaxSliderLabel.Text = 'Contour Max';
            app.ContourMaxSlider = uislider(app.UIFigure);
            app.ImportPartButton = uibutton(app.UIFigure, 'push');
            app.ImportPartButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPartButtonPushed, true);
            app.ImportPartButton.Text = 'Import Part';
            app.ExportPartButton = uibutton(app.UIFigure, 'push');
            app.ExportPartButton.Text = 'Export Part';
            app.ExportFileExtensionDropDownLabel = uilabel(app.UIFigure);
            app.ExportFileExtensionDropDownLabel.Text = 'Export File Extension';
            app.ExportFileExtensionDropDown = uidropdown(app.UIFigure);
            app.ExportFileExtensionDropDown.Items = {'.path'};
            app.ExportFileExtensionDropDown.Value = '.path';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.BuildInfoTextAreaLabel = uilabel(app.UIFigure);
            app.BuildInfoTextAreaLabel.Text = 'Build Info';
            app.BuildInfoTextArea = uitextarea(app.UIFigure);
            app.ResetPartViewButton = uibutton(app.UIFigure, 'push');
            app.ResetPartViewButton.Text = 'Reset Part View';
            app.ResetContourViewButton = uibutton(app.UIFigure, 'push');
            app.ResetContourViewButton.Text = 'Reset Contour View';
            app.ResetSegmentViewButton = uibutton(app.UIFigure, 'push');
            app.ResetSegmentViewButton.Text = 'Reset Segment View';
            app.UNIVERSITYOFTENNESSEEARCLABORATORYLabel = uilabel(app.UIFigure);
            app.UNIVERSITYOFTENNESSEEARCLABORATORYLabel.Text = {'UNIVERSITY OF TENNESSEE ARC LABORATORY'; 'LSAMM PATH PLANNING SOFTWARE'};
            app.ContourMaxUpButton = uibutton(app.UIFigure, 'push');
            app.ContourMaxUpButton.Text = '^';
            app.ContourMaxDownButton = uibutton(app.UIFigure, 'push');
            app.ContourMaxDownButton.Text = 'v';
            app.ContourMinUpButton = uibutton(app.UIFigure, 'push');
            app.ContourMinUpButton.Text = '^';
            app.ContourMinDownButton = uibutton(app.UIFigure, 'push');
            app.ContourMinDownButton.Text = 'v';
            app.SegmentDropDownLabel = uilabel(app.UIFigure);
            app.SegmentDropDownLabel.Text = 'Segment';
            app.SegmentDropDown = uidropdown(app.UIFigure);
            app.SegmentDropDown.Items = {'1'};
            app.SegmentDropDown.ValueChangedFcn = createCallbackFcn(app, @SegmentDropDownValueChanged, true);
            app.SegmentDropDown.Value = '1';
            app.ContourDropDownLabel = uilabel(app.UIFigure);
            app.ContourDropDownLabel.Text = 'Contour';
            app.ContourDropDown = uidropdown(app.UIFigure);
            app.ContourDropDown.Items = {'1'};
            app.ContourDropDown.ValueChangedFcn = createCallbackFcn(app, @ContourDropDownValueChanged, true);
            app.ContourDropDown.Value = '1';
            app.MoveDropDownLabel = uilabel(app.UIFigure);
            app.MoveDropDownLabel.Text = 'Move';
            app.MoveDropDown = uidropdown(app.UIFigure);
            app.MoveDropDown.Items = {'1'};
            app.MoveDropDown.ValueChangedFcn = createCallbackFcn(app, @MoveDropDownValueChanged, true);
            app.MoveDropDown.Value = '1';
            app.WaypointDropDownLabel = uilabel(app.UIFigure);
            app.WaypointDropDownLabel.Text = 'Waypoint';
            app.WaypointDropDown = uidropdown(app.UIFigure);
            app.WaypointDropDown.Items = {'1'};
            app.WaypointDropDown.ValueChangedFcn = createCallbackFcn(app, @WaypointDropDownValueChanged, true);
            app.WaypointDropDown.Value = '1';
            app.PathManipulationAlgorithmsLabel = uilabel(app.UIFigure);
            app.PathManipulationAlgorithmsLabel.Text = 'Path Manipulation Algorithms';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PathPlanner

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end